classdef ADCAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SamplestosaveEditField       matlab.ui.control.NumericEditField
        SamplestosaveEditFieldLabel  matlab.ui.control.Label
        SelectPinsListBox            matlab.ui.control.ListBox
        SelectPinsListBoxLabel       matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        ConnecttoArduinoButton       matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        arduinoObject %object to be able to connect to Arduino
        timerObject %object to call timer function to query ADC results
        buffer %buffer to store data for plot
        count = 1 %count used to keep track of location in buffer
    end
    
    methods (Access = private)
        
        function timerVoltageRead(app,~,~)
            for pinValue = 1:numel(app.SelectPinsListBox.Value)
                app.buffer(app.count, pinValue) = app.arduinoObject.readVoltage(app.SelectPinsListBox.Value{pinValue});
            end

            if app.count < app.SamplestosaveEditField.Value
                app.count = app.count + 1;
     
            else
                app.buffer = circshift(app.buffer, -1, 1);
    
            end

            plot(app.UIAxes, app.buffer);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnecttoArduinoButton
        function ConnecttoArduinoButtonPushed(app, event)
            try 
                app.arduinoObject = arduino;
                app.Lamp.Color = "green";
                app.SelectPinsListBox.Items = app.arduinoObject.AvailableAnalogPins;
                app.SelectPinsListBox.Enable = "on";
                app.ConnecttoArduinoButton.Text = "Connected";
                app.ConnecttoArduinoButton.Enable = "off";
                app.UIAxes.XLim = [1,app.SamplestosaveEditField.Value];
                app.SelectPinsListBox.Multiselect = "on";

                app.timerObject = timer("ExecutionMode","fixedRate","Period",0.1,"TimerFcn",@app.timerVoltageRead);
                start(app.timerObject);
            catch
                %show error code here

            end
        end

        % Value changed function: SelectPinsListBox
        function SelectPinsListBoxValueChanged(app, event)
            app.buffer = NaN(app.SamplestosaveEditField.Value,numel(app.SelectPinsListBox.Value));
            app.count = 1;  
        end

        % Value changed function: SamplestosaveEditField
        function SamplestosaveEditFieldValueChanged(app, event)
            app.UIAxes.XLim = [1 ,app.SamplestosaveEditField.Value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ADC Readings')
            xlabel(app.UIAxes, 'Sample Count')
            ylabel(app.UIAxes, 'Voltage (V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 5];
            app.ConnecttoArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoArduinoButtonPushed, true);
            app.ConnecttoArduinoButton.Text = 'Connect to Arduino';
            app.Lamp = uilamp(app.UIFigure);
            app.SelectPinsListBoxLabel = uilabel(app.UIFigure);
            app.SelectPinsListBoxLabel.Enable = 'off';
            app.SelectPinsListBoxLabel.Text = 'Select Pins:';
            app.SelectPinsListBox = uilistbox(app.UIFigure);
            app.SelectPinsListBox.Items = {};
            app.SelectPinsListBox.Multiselect = 'on';
            app.SelectPinsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectPinsListBoxValueChanged, true);
            app.SelectPinsListBox.Enable = 'off';
            app.SelectPinsListBox.Value = {};
            app.SamplestosaveEditFieldLabel = uilabel(app.UIFigure);
            app.SamplestosaveEditFieldLabel.Text = 'Samples to save:';
            app.SamplestosaveEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplestosaveEditField.Limits = [0 Inf];
            app.SamplestosaveEditField.RoundFractionalValues = 'on';
            app.SamplestosaveEditField.ValueChangedFcn = createCallbackFcn(app, @SamplestosaveEditFieldValueChanged, true);
            app.SamplestosaveEditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADCAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end